classdef ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AButton                     matlab.ui.control.Button
        KDTreeDepthSliderLabel      matlab.ui.control.Label
        KDTreeDepthSlider           matlab.ui.control.Slider
        antalgorithmButton          matlab.ui.control.Button
        clearpointsandpathsButton   matlab.ui.control.Button
        ParallelizedSwitchLabel     matlab.ui.control.Label
        ParallelizedSwitch          matlab.ui.control.Switch
        alphaEditFieldLabel         matlab.ui.control.Label
        alphaEditField              matlab.ui.control.EditField
        betaEditFieldLabel          matlab.ui.control.Label
        betaEditField               matlab.ui.control.EditField
        rhoEditFieldLabel           matlab.ui.control.Label
        rhoEditField                matlab.ui.control.EditField
        deltaEditFieldLabel         matlab.ui.control.Label
        deltaEditField              matlab.ui.control.EditField
        maxIterEditFieldLabel       matlab.ui.control.Label
        maxIterEditField            matlab.ui.control.EditField
        pointstopickEditFieldLabel  matlab.ui.control.Label
        pointstopickEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        kd_tree;
        current_kd_depth;
        adjMatrix;
        startEndList;
        startEndListGeo;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %higher precision
            format long;
            digits(64);

            %load boston roads into UIAxes
            roads = shaperead('boston_roads.shp'); 
            worldFile = 'myboston.jgw'; 
            img = imread('myboston3.jpg'); 
            R = worldfileread(worldFile); 
            geoshow(img, R); 
 
            %load kd tree
            load('kd_tree5.mat');
            app.kd_tree = kd_tree;
            app.current_kd_depth = 5;
            
            %load lists and adjMatrix
            [app.adjMatrix, app.startEndList, app.startEndListGeo] = shpToGeoEucAdj(roads);
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            %higher precision
            format long;
            digits(64);
            
            [startLon, startLat] = ginput(1);
            startPoint = [startLat startLon];
            
            points = search_kd_tree(startPoint, app.kd_tree);
            foundStart = pointMatch(startPoint, points);
            
            roadCount = length(app.startEndList)/2;
            
            geoshow(startLat, startLon, 'DisplayType', 'point', 'MarkerEdgeColor', 'black')
            geoshow(foundStart(1,1), foundStart(1,2) , 'DisplayType', 'point', 'MarkerEdgeColor', 'green')
            
            disp("selected start point: " + startPoint);
            disp("nearest start point: " + foundStart);
            startIndex = foundStart(1,3);
            
            [endLon, endLat] = ginput(1);
            endPoint = [endLat endLon];
            
            points = search_kd_tree(endPoint, app.kd_tree);
            foundEnd = pointMatch(endPoint, points);
            endIndex = foundEnd(1,3);
            disp("selected end point: " + endPoint);
            disp("nearest end point: " + foundEnd);
            
            geoshow(endLat, endLon, 'DisplayType', 'point', 'MarkerEdgeColor', 'black')
            geoshow(foundEnd(1,1), foundEnd(1,2) , 'DisplayType', 'point', 'MarkerEdgeColor', 'green')
            
            disp("selected start point: " + endPoint);
            disp("nearest start point: " + foundEnd);
            
            if(app.ParallelizedSwitch.Value == "Off")
                tic
                aStarPathFinder(...
                    app.adjMatrix, ...
                    app.startEndList,...
                    app.startEndListGeo,...
                    startIndex,...
                    endIndex...
                 );
                toc
                disp("time end");
            else
                tic
                [threadResult1, threadResult2] = aStarPathFinderX(...
                    app.adjMatrix, ...
                    app.startEndList,...
                    app.startEndListGeo,...
                    startIndex,...
                    endIndex...
                );
                
                traversalList1 = threadResult1{1};
                traversalList2 = threadResult2{2};

                load("roads_geo_out.mat");
                for i=2:length(traversalList1)-1
                       currentRoadIndex = traversalList1(i);
                       if(currentRoadIndex > roadCount) currentRoadIndex = currentRoadIndex - roadCount; end
                       geoshow(roads_geo_out(currentRoadIndex), 'color', 'red', 'LineWidth', 4);
                end
                
                for i=2:length(traversalList2)-1
                       currentRoadIndex = traversalList2(i);
                       if(currentRoadIndex > roadCount) currentRoadIndex = currentRoadIndex - roadCount; end
                       geoshow(roads_geo_out(currentRoadIndex), 'color', 'cyan', 'LineWidth', 4);
                end
                toc
                disp("time parallel end");
            end
        end

        % Value changed function: KDTreeDepthSlider
        function KDTreeDepthSliderValueChanged(app, event)
            value = app.KDTreeDepthSlider.Value;
            value = floor(value);

            if ~(app.current_kd_depth == value)
                load(strcat('kd_tree', int2str(value),'.mat'));
                app.kd_tree = kd_tree;
                app.current_kd_depth = value;
                disp("changed kd tree depth to " + value);
            end
            
        end

        % Button pushed function: clearpointsandpathsButton
        function clearpointsandpathsButtonPushed(app, event)
           clearPointsAndPaths();
        end

        % Button pushed function: antalgorithmButton
        function antalgorithmButtonPushed(app, event)
            alpha = str2double(app.alphaEditField.Value);
            beta = str2double(app.betaEditField.Value);
            rho = str2double(app.rhoEditField.Value);
            delta = str2double(app.deltaEditField.Value);
            maxIter = str2double(app.maxIterEditField.Value);
            pointCount = str2double(app.pointstopickEditField.Value);
            
            indices = [];
            
            for i=1:pointCount
                [endLon, endLat] = ginput(1);
                endPoint = [endLat endLon];
                
                points = search_kd_tree(endPoint, app.kd_tree);
                foundEnd = pointMatch(endPoint, points);
                indices = [indices; foundEnd(1,3)];
                
                geoshow(endLat, endLon, 'DisplayType', 'point', 'MarkerEdgeColor', 'black')
                geoshow(foundEnd(1,1), foundEnd(1,2) , 'DisplayType', 'point', 'MarkerEdgeColor', 'green')
            end
            
            disp("selected startEndList indices");
            disp(indices);

            
            bestTour =...
            antAlgo(indices, app.startEndList, alpha, beta, rho, delta, maxIter);
            
            
            %add the first index to itself to make a round
            bestTour = [bestTour bestTour(1)];
            indices = [indices; indices(1)];
            
            %disp("best tour before add: " + bestTour);
            disp("best tour indices: " + bestTour);
            
            %map to startEndList Indices
            for i=1:length(bestTour)
                bestTour(i) = indices(bestTour(i));
            end
            
            disp("best tour startEndList:");
            disp(bestTour);
        
            for i=1:length(bestTour)-1
                disp("drawing tour: " + i);
                    aStarPathFinder(...
                   app.adjMatrix, ...
                   app.startEndList,...
                   app.startEndListGeo,...
                   bestTour(i),...
                   bestTour(i+1)...
                );
            pause(1);
            end
        
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A*';
            app.KDTreeDepthSliderLabel = uilabel(app.UIFigure);
            app.KDTreeDepthSliderLabel.Text = 'KD Tree Depth';
            app.KDTreeDepthSlider = uislider(app.UIFigure);
            app.KDTreeDepthSlider.Limits = [5 9];
            app.KDTreeDepthSlider.MajorTicks = [5 6 7 8 9];
            app.KDTreeDepthSlider.ValueChangedFcn = createCallbackFcn(app, @KDTreeDepthSliderValueChanged, true);
            app.KDTreeDepthSlider.MinorTicks = [5 6 7 8 9];
            app.KDTreeDepthSlider.Value = 5;
            app.antalgorithmButton = uibutton(app.UIFigure, 'push');
            app.antalgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @antalgorithmButtonPushed, true);
            app.antalgorithmButton.Text = 'ant algorithm';
            app.clearpointsandpathsButton = uibutton(app.UIFigure, 'push');
            app.clearpointsandpathsButton.ButtonPushedFcn = createCallbackFcn(app, @clearpointsandpathsButtonPushed, true);
            app.clearpointsandpathsButton.Text = 'clear points and paths';
            app.ParallelizedSwitchLabel = uilabel(app.UIFigure);
            app.ParallelizedSwitchLabel.Text = 'Parallelized';
            app.ParallelizedSwitch = uiswitch(app.UIFigure, 'slider');
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'text');
            app.alphaEditField.Value = '0.3';
            app.betaEditFieldLabel = uilabel(app.UIFigure);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.UIFigure, 'text');
            app.betaEditField.Value = '0.7';
            app.rhoEditFieldLabel = uilabel(app.UIFigure);
            app.rhoEditFieldLabel.Text = 'rho';
            app.rhoEditField = uieditfield(app.UIFigure, 'text');
            app.rhoEditField.Value = '0.05';
            app.deltaEditFieldLabel = uilabel(app.UIFigure);
            app.deltaEditFieldLabel.Text = 'delta';
            app.deltaEditField = uieditfield(app.UIFigure, 'text');
            app.deltaEditField.Value = '0.4';
            app.maxIterEditFieldLabel = uilabel(app.UIFigure);
            app.maxIterEditFieldLabel.Text = 'maxIter';
            app.maxIterEditField = uieditfield(app.UIFigure, 'text');
            app.maxIterEditField.Value = '10';
            app.pointstopickEditFieldLabel = uilabel(app.UIFigure);
            app.pointstopickEditFieldLabel.Text = 'points to pick';
            app.pointstopickEditField = uieditfield(app.UIFigure, 'text');
            app.pointstopickEditField.Value = '5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end